classdef StitchingChecker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        WidefieldImagePanel             matlab.ui.container.Panel
        WF_SelectFile                   matlab.ui.control.Button
        FilepathEditFieldLabel          matlab.ui.control.Label
        WF_FilePathPanel                matlab.ui.control.EditField
        AlphaSliderLabel                matlab.ui.control.Label
        WF_Alpha                        matlab.ui.control.Slider
        DeleteWF                        matlab.ui.control.Button
        MINI2PImageNeuronInformationmatPanel  matlab.ui.container.Panel
        SizeSpinner_2Label              matlab.ui.control.Label
        MINI2P_resizeFactor             matlab.ui.control.Spinner
        RotateSpinner_2Label            matlab.ui.control.Label
        RotateSpinner_2                 matlab.ui.control.Spinner
        AlphasingleSliderLabel          matlab.ui.control.Label
        AlphasingleSlider               matlab.ui.control.Slider
        ShiftXSpinner_2Label            matlab.ui.control.Label
        ShiftXSpinner_2                 matlab.ui.control.Spinner
        ShiftYSpinner_2Label            matlab.ui.control.Label
        ShiftYSpinner_2                 matlab.ui.control.Spinner
        AlphaallSliderLabel             matlab.ui.control.Label
        AlphaallSlider                  matlab.ui.control.Slider
        StitchibgPoorListBoxLabel       matlab.ui.control.Label
        StitchibgPoorListBox            matlab.ui.control.ListBox
        AddFOV                          matlab.ui.control.Button
        DeleteButton_2                  matlab.ui.control.Button
        ChannelDropDownLabel            matlab.ui.control.Label
        ChannelDropDown                 matlab.ui.control.DropDown
        UITable                         matlab.ui.control.Table
        PlaneDropDownLabel              matlab.ui.control.Label
        PlaneDropDown                   matlab.ui.control.DropDown
        NumberofcellsLabel              matlab.ui.control.Label
        TypeDropDownLabel               matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        AlphasingleSlider_2Label        matlab.ui.control.Label
        AlphasingleSlider_2             matlab.ui.control.Slider
        AlphaallSlider_2Label           matlab.ui.control.Label
        AlphaallSlider_2                matlab.ui.control.Slider
        ColorbyDropDownLabel            matlab.ui.control.Label
        ColorbyDropDown                 matlab.ui.control.DropDown
        ColormapDropDownLabel           matlab.ui.control.Label
        ColormapDropDown                matlab.ui.control.DropDown
        CellROICheckBox                 matlab.ui.control.CheckBox
        DonotdocorrectionCheckBox       matlab.ui.control.CheckBox
        FOVEditFieldLabel               matlab.ui.control.Label
        FOVEditField                    matlab.ui.control.NumericEditField
        ReplaceButton                   matlab.ui.control.Button
        ProcessedDataInputPanel         matlab.ui.container.Panel
        SelectButton_2                  matlab.ui.control.Button
        OutputButton                    matlab.ui.control.Button
        FilepathEditField_2Label        matlab.ui.control.Label
        FilepathEditField_2             matlab.ui.control.EditField
        InputButton                     matlab.ui.control.Button
        StitchingPreviewPanel           matlab.ui.container.Panel
        WFAxes                          matlab.ui.control.UIAxes
        RetinotopicMappingPanel         matlab.ui.container.Panel
        RetMapEditFieldLabel            matlab.ui.control.Label
        RetMapEditField                 matlab.ui.control.EditField
        SelectRMButton                  matlab.ui.control.Button
        AlphaSliderLabel_2              matlab.ui.control.Label
        RM_alpha                        matlab.ui.control.Slider
        MeandFFEditFieldLabel           matlab.ui.control.Label
        MeandFFEditField                matlab.ui.control.EditField
        SelectDFButton                  matlab.ui.control.Button
        DeleteRM                        matlab.ui.control.Button
        MINI2PCorrectionTransfomrMatrixPanel  matlab.ui.container.Panel
        LoadButton                      matlab.ui.control.Button
        FilepathEditField_4Label        matlab.ui.control.Label
        TransMatrix_filepath            matlab.ui.control.EditField
        CroesscorrelationPanel          matlab.ui.container.Panel
        CheckCorrelationButton          matlab.ui.control.Button
        TotheWidefieldCheckBox          matlab.ui.control.CheckBox
        RangeLabel                      matlab.ui.control.Label
        RangeEditField                  matlab.ui.control.NumericEditField
        UIAxes2                         matlab.ui.control.UIAxes
        PeakvalueEditFieldLabel         matlab.ui.control.Label
        PeakvalueEditField              matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        MinoverlapEditFieldLabel        matlab.ui.control.Label
        MinoverlapEditField             matlab.ui.control.NumericEditField
        RemoverepeatedcellsPanel        matlab.ui.container.Panel
        OverlapthresholdEditFieldLabel  matlab.ui.control.Label
        OverlapthresholdEditField       matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        RemoverepearedcellsButton       matlab.ui.control.Button
        KeepcellDropDownLabel           matlab.ui.control.Label
        KeepcellDropDown                matlab.ui.control.DropDown
        WidefieldtransformationPanel    matlab.ui.container.Panel
        ShiftXSpinnerLabel              matlab.ui.control.Label
        WF_ShiftX                       matlab.ui.control.Spinner
        ShiftYSpinnerLabel              matlab.ui.control.Label
        WF_ShiftY                       matlab.ui.control.Spinner
        SizeSpinnerLabel                matlab.ui.control.Label
        WF_Resize                       matlab.ui.control.Spinner
        RotateSpinnerLabel              matlab.ui.control.Label
        WF_Rotate                       matlab.ui.control.Spinner
        WF_SelectFile_2                 matlab.ui.control.Button
        TextboxListBoxLabel             matlab.ui.control.Label
        Textbox                         matlab.ui.control.ListBox
    end

    
    properties (Access = public)
        WF_file % file name for wide field image
        WF_path % file path for wide field image
        WF_Image_raw % raw wide-field image 
        WF_Image_normalized % raw wide-field image 
        WF_Image_shift % raw wide-field image
        RM_file % file name for wide field image
        RM_path % file path for wide field image
        RM_Image_raw % raw wide-field image 
        RM_Image_normalized % raw wide-field image 
        RM_Image_shift % raw wide-field image 
        H_WF % Handle for wide-field image 
        H_RM % Handle for wide-field image 
        DF_Image
        DF_Image_shift
        WF_Imageloaded=0;
        RM_Imageloaded=0;
        DF_Imageloaded=0;
        StitchingPoor={};


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WF_SelectFile
        function WF_SelectFilePushed(app, event)
            [app.WF_file,app.WF_path] = uigetfile('*.tif','Please select the wide-field image'); 
            cd (app.WF_path);
            app.WF_Image_raw=imread([app.WF_path,'\',app.WF_file]);
            app.WF_FilePathPanel.Value=[app.WF_path,'\',app.WF_file];
            app.WF_Image_raw=double(app.WF_Image_raw);
            app.WF_Image_normalized=(app.WF_Image_raw-min(app.WF_Image_raw(:)))./(max(app.WF_Image_raw(:))-min(app.WF_Image_raw(:)));
            app.WF_Image_shift=imresize(app.WF_Image_normalized,app.WF_Resize.Value,'bilinear');
            app.WF_Image_shift=imrotate(app.WF_Image_normalized,app.WF_Rotate.Value,'bilinear');
            if ~isempty(app.H_WF)
                delete(app.H_WF) 
%                 HD=app.H_RM
%                 cla(handles.HD,'reset')
            else
            end
            app.H_WF=image(app.WFAxes,app.WF_ShiftX.Value,app.WF_ShiftY.Value,uint8(255*app.WF_Image_shift));
            app.H_WF.AlphaData=app.WF_Alpha.Value;
            colormap(app.WFAxes,gray)
            app.WFAxes.DataAspectRatio=[1 1 1];
            hold(app.WFAxes,'off')
        end

        % Value changed function: WF_FilePathPanel
        function WF_FilePathPanelValueChanged(app, event)
            value = [app.WF_path,'\',app.WF_file];
            
        end

        % Value changed function: MINI2P_resizeFactor
        function MINI2P_resizeFactorValueChanged(app, event)
            value= app.MINI2P_resizeFactor.Value;

            
        end

        % Value changed function: WF_Rotate
        function WF_RotateValueChanged(app, event)
            if ~isempty(app.WF_Image_normalized)
                app.WF_Image_shift=imresize(app.WF_Image_normalized,app.WF_Resize.Value,'bilinear');
                app.WF_Image_shift=imrotate(app.WF_Image_shift,app.WF_Rotate.Value,'bilinear');
            else
            end
            if ~isempty(app.RM_Image_normalized)
                app.RM_Image_shift=imresize(app.RM_Image_normalized,app.WF_Resize.Value,'bilinear');
                app.RM_Image_shift=imrotate(app.RM_Image_shift,app.WF_Rotate.Value,'bilinear');
            else
            end
            if ~isempty(app.DF_Image)
                app.DF_Image_shift=imresize(app.DF_Image,app.WF_Resize.Value,'bilinear');
                app.DF_Image_shift=imrotate(app.DF_Image_shift,app.WF_Rotate.Value,'bilinear');
            else
            end

            if ~isempty(app.H_WF)
                delete(app.H_WF)                
                app.H_WF=image(app.WFAxes,app.WF_ShiftX.Value,app.WF_ShiftY.Value,uint8(255*app.WF_Image_shift));         
                app.H_WF.AlphaData=app.WF_Alpha.Value;                    
            else
            end
            hold (app.WFAxes,'on')  

            RM_Image_shift_rgb = ind2rgb(uint8(255*app.RM_Image_shift), jet(256));            
            if ~isempty(app.H_RM)
                delete(app.H_RM) 
                app.H_RM=image(app.WFAxes,app.WF_ShiftX.Value,app.WF_ShiftY.Value,RM_Image_shift_rgb);          
                if app.DF_Imageloaded==0
                    app.H_RM.AlphaData=app.RM_alpha.Value;
                else
                    app.H_RM.AlphaData=app.RM_alpha.Value.*app.DF_Image_shift;
                end
            else
            end           
            app.WFAxes.DataAspectRatio=[1 1 1];
            hold (app.WFAxes,'off')
        end

        % Value changed function: AlphasingleSlider
        function AlphasingleSliderValueChanged(app, event)
            value = app.AlphasingleSlider.Value;
            
        end

        % Value changed function: WF_Alpha
        function WF_AlphaValueChanged(app, event)
            app.H_WF.AlphaData=app.WF_Alpha.Value;            
        end

        % Value changed function: WF_Resize
        function WF_ResizeValueChanged(app, event)
            if ~isempty(app.WF_Image_normalized)
                app.WF_Image_shift=imresize(app.WF_Image_normalized,app.WF_Resize.Value,'bilinear');
                app.WF_Image_shift=imrotate(app.WF_Image_shift,app.WF_Rotate.Value,'bilinear');
            else
            end
            if ~isempty(app.RM_Image_normalized)
                app.RM_Image_shift=imresize(app.RM_Image_normalized,app.WF_Resize.Value,'bilinear');
                app.RM_Image_shift=imrotate(app.RM_Image_shift,app.WF_Rotate.Value,'bilinear');
            else
            end
            if ~isempty(app.DF_Image)
                app.DF_Image_shift=imresize(app.DF_Image,app.WF_Resize.Value,'bilinear');
                app.DF_Image_shift=imrotate(app.DF_Image_shift,app.WF_Rotate.Value,'bilinear');
            else
            end
            if ~isempty(app.H_WF)
                delete(app.H_WF) 
            else
            end
            hold (app.WFAxes,'on')            
            app.H_WF=image(app.WFAxes,app.WF_ShiftX.Value,app.WF_ShiftY.Value,uint8(255*app.WF_Image_shift));         
            app.H_WF.AlphaData=app.WF_Alpha.Value;    
            
            RM_Image_shift_rgb = ind2rgb(uint8(255*app.RM_Image_shift), jet(256));            
            if ~isempty(app.H_RM)
                delete(app.H_RM) 
            else
            end  
            hold (app.WFAxes,'on')             
            app.H_RM=image(app.WFAxes,app.WF_ShiftX.Value,app.WF_ShiftY.Value,RM_Image_shift_rgb);
            if app.DF_Imageloaded==0
                app.H_RM.AlphaData=app.RM_alpha.Value;
            else
                app.H_RM.AlphaData=app.RM_alpha.Value.*app.DF_Image_shift;
            end            
            app.WFAxes.DataAspectRatio=[1 1 1];
            hold (app.WFAxes,'off')
        end

        % Value changed function: WF_ShiftY
        function WF_ShiftYValueChanged(app, event)
            if ~isempty(app.WF_Image_normalized)
                app.WF_Image_shift=imresize(app.WF_Image_normalized,app.WF_Resize.Value,'bilinear');
                app.WF_Image_shift=imrotate(app.WF_Image_shift,app.WF_Rotate.Value,'bilinear');
            else
            end
            if ~isempty(app.RM_Image_normalized)
                app.RM_Image_shift=imresize(app.RM_Image_normalized,app.WF_Resize.Value,'bilinear');
                app.RM_Image_shift=imrotate(app.RM_Image_shift,app.WF_Rotate.Value,'bilinear');
            else
            end
            if ~isempty(app.DF_Image)
                app.DF_Image_shift=imresize(app.DF_Image,app.WF_Resize.Value,'bilinear');
                app.DF_Image_shift=imrotate(app.DF_Image_shift,app.WF_Rotate.Value,'bilinear');
            else
            end
            if ~isempty(app.H_WF)
                delete(app.H_WF) 
            else
            end
            hold (app.WFAxes,'on')            
            app.H_WF=image(app.WFAxes,app.WF_ShiftX.Value,app.WF_ShiftY.Value,uint8(255*app.WF_Image_shift));         
            app.H_WF.AlphaData=app.WF_Alpha.Value;    
            
            RM_Image_shift_rgb = ind2rgb(uint8(255*app.RM_Image_shift), jet(256));            
            if ~isempty(app.H_RM)
                delete(app.H_RM) 
            else
            end  
            hold (app.WFAxes,'on')             
            app.H_RM=image(app.WFAxes,app.WF_ShiftX.Value,app.WF_ShiftY.Value,RM_Image_shift_rgb);
            if app.DF_Imageloaded==0
                app.H_RM.AlphaData=app.RM_alpha.Value;
            else
                app.H_RM.AlphaData=app.RM_alpha.Value.*app.DF_Image_shift;
            end            
            app.WFAxes.DataAspectRatio=[1 1 1];
            hold (app.WFAxes,'off')
        end

        % Value changed function: WF_ShiftX
        function WF_ShiftXValueChanged(app, event)
            if ~isempty(app.WF_Image_normalized)
                app.WF_Image_shift=imresize(app.WF_Image_normalized,app.WF_Resize.Value,'bilinear');
                app.WF_Image_shift=imrotate(app.WF_Image_shift,app.WF_Rotate.Value,'bilinear');
            else
            end
            if ~isempty(app.RM_Image_normalized)
                app.RM_Image_shift=imresize(app.RM_Image_normalized,app.WF_Resize.Value,'bilinear');
                app.RM_Image_shift=imrotate(app.RM_Image_shift,app.WF_Rotate.Value,'bilinear');
            else
            end
            if ~isempty(app.DF_Image)
                app.DF_Image_shift=imresize(app.DF_Image,app.WF_Resize.Value,'bilinear');
                app.DF_Image_shift=imrotate(app.DF_Image_shift,app.WF_Rotate.Value,'bilinear');
            else
            end
            if ~isempty(app.H_WF)
                delete(app.H_WF) 
            else
            end
            hold (app.WFAxes,'on')            
            app.H_WF=image(app.WFAxes,app.WF_ShiftX.Value,app.WF_ShiftY.Value,uint8(255*app.WF_Image_shift));         
            app.H_WF.AlphaData=app.WF_Alpha.Value;    
            
            RM_Image_shift_rgb = ind2rgb(uint8(255*app.RM_Image_shift), jet(256));            
            if ~isempty(app.H_RM)
                delete(app.H_RM) 
            else
            end  
            hold (app.WFAxes,'on')             
            app.H_RM=image(app.WFAxes,app.WF_ShiftX.Value,app.WF_ShiftY.Value,RM_Image_shift_rgb);
            if app.DF_Imageloaded==0
                app.H_RM.AlphaData=app.RM_alpha.Value;
            else
                app.H_RM.AlphaData=app.RM_alpha.Value.*app.DF_Image_shift;
            end            
            app.WFAxes.DataAspectRatio=[1 1 1];
            hold (app.WFAxes,'off')
        end

        % Button pushed function: SelectRMButton
        function SelectRMButtonPushed(app, event)
            [app.RM_file,app.RM_path] = uigetfile('*.tif','Please select the retinotopic mapping image');
            cd (app.RM_path);
            app.RM_Image_raw=imread([app.RM_path,'\',app.RM_file]);
            app.RetMapEditField.Value=[app.RM_path,'\',app.RM_file];
            app.RM_Image_raw=double(app.RM_Image_raw);
            app.RM_Image_normalized=(app.RM_Image_raw-min(app.RM_Image_raw(:)))./(max(app.RM_Image_raw(:))-min(app.RM_Image_raw(:)));
            app.RM_Image_shift=imresize(app.RM_Image_normalized,app.WF_Resize.Value,'bilinear');
            app.RM_Image_shift=imrotate(app.RM_Image_shift,app.WF_Rotate.Value,'bilinear'); 
            if ~isempty(app.H_RM)
                delete(app.H_RM) 
            else
            end
            hold (app.WFAxes,'on');  
            RM_Image_shift_rgb = ind2rgb(uint8(255*app.RM_Image_shift), jet(256));           
            app.H_RM=image(app.WFAxes,app.WF_ShiftX.Value,app.WF_ShiftY.Value,RM_Image_shift_rgb);
            if app.DF_Imageloaded==0
                app.H_RM.AlphaData=app.RM_alpha.Value;
            else
                app.H_RM.AlphaData=app.RM_alpha.Value.*app.DF_Image_shift;
            end


        end

        % Value changed function: RM_alpha
        function RM_alphaValueChanged(app, event)

              if app.DF_Imageloaded==0
                app.H_RM.AlphaData=app.RM_alpha.Value;
              else
                app.H_RM.AlphaData=app.RM_alpha.Value.*app.DF_Image_shift;
              end

        end

        % Button pushed function: SelectDFButton
        function SelectDFButtonPushed(app, event)
            [DF_file,DF_path] = uigetfile('*.tif','Please select the averaged dF/F image');
            cd (DF_path);
            app.DF_Image=imread([DF_path,'\',DF_file]);
            app.MeandFFEditField.Value=[DF_path,'\',DF_file];
            app.DF_Imageloaded=1;
            app.DF_Image=double(app.DF_Image);
            app.DF_Image=(app.DF_Image-min(app.DF_Image(:)))./(max(app.DF_Image(:))-min(app.DF_Image(:)));                     
            app.DF_Image_shift=imresize(app.DF_Image,app.WF_Resize.Value,'bilinear');
            app.DF_Image_shift=imrotate(app.DF_Image_shift,app.WF_Rotate.Value,'bilinear');       
            app.H_RM.AlphaData=app.RM_alpha.Value.*app.DF_Image_shift;
        end

        % Button pushed function: WF_SelectFile_2
        function WF_SelectFile_2ButtonPushed(app, event)
            app.WF_Resize.Value=1;
            app.WF_Rotate.Value=0;
            app.WF_ShiftX.Value=0;
            app.WF_ShiftY.Value=0;
            
            if ~isempty(app.WF_Image_normalized)
                app.WF_Image_shift=imresize(app.WF_Image_normalized,app.WF_Resize.Value,'bilinear');
                app.WF_Image_shift=imrotate(app.WF_Image_shift,app.WF_Rotate.Value,'bilinear');
            else
            end
            if ~isempty(app.RM_Image_normalized)
                app.RM_Image_shift=imresize(app.RM_Image_normalized,app.WF_Resize.Value,'bilinear');
                app.RM_Image_shift=imrotate(app.RM_Image_shift,app.WF_Rotate.Value,'bilinear');
            else
            end
            if ~isempty(app.DF_Image)
                app.DF_Image_shift=imresize(app.DF_Image,app.WF_Resize.Value,'bilinear');
                app.DF_Image_shift=imrotate(app.DF_Image_shift,app.WF_Rotate.Value,'bilinear');
            else
            end
            if ~isempty(app.H_WF)
                delete(app.H_WF) 
            else
            end
            hold (app.WFAxes,'on')            
            app.H_WF=image(app.WFAxes,app.WF_ShiftX.Value,app.WF_ShiftY.Value,uint8(255*app.WF_Image_shift));         
            app.H_WF.AlphaData=app.WF_Alpha.Value;    
            
            RM_Image_shift_rgb = ind2rgb(uint8(255*app.RM_Image_shift), jet(256));            
            if ~isempty(app.H_RM)
                delete(app.H_RM) 
            else
            end  
            hold (app.WFAxes,'on')             
            app.H_RM=image(app.WFAxes,app.WF_ShiftX.Value,app.WF_ShiftY.Value,RM_Image_shift_rgb);
            if app.DF_Imageloaded==0
                app.H_RM.AlphaData=app.RM_alpha.Value;
            else
                app.H_RM.AlphaData=app.RM_alpha.Value.*app.DF_Image_shift;
            end            
            app.WFAxes.DataAspectRatio=[1 1 1];
            hold (app.WFAxes,'off')
        end

        % Button pushed function: DeleteWF
        function DeleteWFButtonPushed(app, event)
            if ~isempty(app.H_WF)
                delete(app.H_WF)
                app.H_WF=[];
            else
            end
        end

        % Button pushed function: DeleteRM
        function DeleteRMButtonPushed(app, event)
            if ~isempty(app.H_RM)
                delete(app.H_RM)
                app.H_RM=[];
            else
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)

            tem=load(app.TransMatrix_filepath.Value)
        end

        % Button pushed function: AddFOV
        function AddFOVButtonPushed(app, event)
            app.FOVEditField.Value=length(StitchingPoor)+1;
            [file,path] = uigetfile('*.mat','Please select the NeuronInformation.mat file'); 
            cd (path);
            tem=load([path,'\',file])                       
            app.Textbox.Items(length(app.Textbox.Items)+1)={[path,'\',file,' is loaded as FOV ', num2str(app.FOVEditField.Value)]};
            StitchingPoor{length(StitchingPoor)+1,1}=tem;
            app.StitchibgPoorListBox.Items(length(StitchingPoor))={['FOV',num2str(app.FOVEditField.Value)]};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WidefieldImagePanel = uipanel(app.UIFigure);
            app.WidefieldImagePanel.Title = 'Wide-field Image';
            app.WF_SelectFile = uibutton(app.WidefieldImagePanel, 'push');
            app.WF_SelectFile.ButtonPushedFcn = createCallbackFcn(app, @WF_SelectFilePushed, true);
            app.WF_SelectFile.Text = 'Select';
            app.FilepathEditFieldLabel = uilabel(app.WidefieldImagePanel);
            app.FilepathEditFieldLabel.Text = 'File path';
            app.WF_FilePathPanel = uieditfield(app.WidefieldImagePanel, 'text');
            app.WF_FilePathPanel.ValueChangedFcn = createCallbackFcn(app, @WF_FilePathPanelValueChanged, true);
            app.AlphaSliderLabel = uilabel(app.WidefieldImagePanel);
            app.AlphaSliderLabel.Text = 'Alpha';
            app.WF_Alpha = uislider(app.WidefieldImagePanel);
            app.WF_Alpha.Limits = [0 1];
            app.WF_Alpha.ValueChangedFcn = createCallbackFcn(app, @WF_AlphaValueChanged, true);
            app.WF_Alpha.Value = 1;
            app.DeleteWF = uibutton(app.WidefieldImagePanel, 'push');
            app.DeleteWF.ButtonPushedFcn = createCallbackFcn(app, @DeleteWFButtonPushed, true);
            app.DeleteWF.Text = 'Delete';
            app.MINI2PImageNeuronInformationmatPanel = uipanel(app.UIFigure);
            app.MINI2PImageNeuronInformationmatPanel.Title = 'MINI2P Image (NeuronInformation.mat)';
            app.SizeSpinner_2Label = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.SizeSpinner_2Label.Text = 'Size';
            app.MINI2P_resizeFactor = uispinner(app.MINI2PImageNeuronInformationmatPanel);
            app.MINI2P_resizeFactor.ValueChangedFcn = createCallbackFcn(app, @MINI2P_resizeFactorValueChanged, true);
            app.MINI2P_resizeFactor.Value = 1;
            app.RotateSpinner_2Label = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.RotateSpinner_2Label.Text = 'Rotate';
            app.RotateSpinner_2 = uispinner(app.MINI2PImageNeuronInformationmatPanel);
            app.AlphasingleSliderLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.AlphasingleSliderLabel.Text = 'Alpha (single)';
            app.AlphasingleSlider = uislider(app.MINI2PImageNeuronInformationmatPanel);
            app.AlphasingleSlider.Limits = [0 1];
            app.AlphasingleSlider.ValueChangedFcn = createCallbackFcn(app, @AlphasingleSliderValueChanged, true);
            app.AlphasingleSlider.Value = 1;
            app.ShiftXSpinner_2Label = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.ShiftXSpinner_2Label.Text = 'Shift-X';
            app.ShiftXSpinner_2 = uispinner(app.MINI2PImageNeuronInformationmatPanel);
            app.ShiftYSpinner_2Label = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.ShiftYSpinner_2Label.Text = 'Shift-Y';
            app.ShiftYSpinner_2 = uispinner(app.MINI2PImageNeuronInformationmatPanel);
            app.AlphaallSliderLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.AlphaallSliderLabel.Text = 'Alpha (all)';
            app.AlphaallSlider = uislider(app.MINI2PImageNeuronInformationmatPanel);
            app.AlphaallSlider.Limits = [0 1];
            app.AlphaallSlider.Value = 1;
            app.StitchibgPoorListBoxLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.StitchibgPoorListBoxLabel.Text = 'Stitchibg Poor';
            app.StitchibgPoorListBox = uilistbox(app.MINI2PImageNeuronInformationmatPanel);
            app.StitchibgPoorListBox.Items = {};
            app.StitchibgPoorListBox.Value = {};
            app.AddFOV = uibutton(app.MINI2PImageNeuronInformationmatPanel, 'push');
            app.AddFOV.ButtonPushedFcn = createCallbackFcn(app, @AddFOVButtonPushed, true);
            app.AddFOV.Text = 'Add';
            app.DeleteButton_2 = uibutton(app.MINI2PImageNeuronInformationmatPanel, 'push');
            app.DeleteButton_2.Text = 'Delete';
            app.ChannelDropDownLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.MINI2PImageNeuronInformationmatPanel);
            app.ChannelDropDown.Items = {'1', '2', '3', '4'};
            app.ChannelDropDown.Value = '1';
            app.UITable = uitable(app.MINI2PImageNeuronInformationmatPanel);
            app.UITable.ColumnName = {'FOV'; 'P1'; 'P2'; 'P3'; 'P4'};
            app.UITable.RowName = {};
            app.PlaneDropDownLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.PlaneDropDownLabel.Text = 'Plane';
            app.PlaneDropDown = uidropdown(app.MINI2PImageNeuronInformationmatPanel);
            app.PlaneDropDown.Items = {'1', '2', '3', '4'};
            app.PlaneDropDown.Value = '1';
            app.NumberofcellsLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.NumberofcellsLabel.Text = 'Number of cells';
            app.TypeDropDownLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.MINI2PImageNeuronInformationmatPanel);
            app.TypeDropDown.Items = {'Average', 'Max'};
            app.TypeDropDown.Value = 'Max';
            app.AlphasingleSlider_2Label = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.AlphasingleSlider_2Label.Text = 'Alpha (single)';
            app.AlphasingleSlider_2 = uislider(app.MINI2PImageNeuronInformationmatPanel);
            app.AlphasingleSlider_2.Limits = [0 1];
            app.AlphasingleSlider_2.Value = 1;
            app.AlphaallSlider_2Label = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.AlphaallSlider_2Label.Text = 'Alpha (all)';
            app.AlphaallSlider_2 = uislider(app.MINI2PImageNeuronInformationmatPanel);
            app.AlphaallSlider_2.Limits = [0 1];
            app.AlphaallSlider_2.Value = 1;
            app.ColorbyDropDownLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.ColorbyDropDownLabel.Text = 'Color by';
            app.ColorbyDropDown = uidropdown(app.MINI2PImageNeuronInformationmatPanel);
            app.ColorbyDropDown.Items = {'FOV', 'COotex', 'Pllane'};
            app.ColorbyDropDown.Value = 'FOV';
            app.ColormapDropDownLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.MINI2PImageNeuronInformationmatPanel);
            app.ColormapDropDown.Items = {'HSV', 'Jet', 'Winter', 'AAAS', 'Nature', 'Custom'};
            app.ColormapDropDown.Value = 'HSV';
            app.CellROICheckBox = uicheckbox(app.MINI2PImageNeuronInformationmatPanel);
            app.CellROICheckBox.Text = 'Cell ROI';
            app.DonotdocorrectionCheckBox = uicheckbox(app.MINI2PImageNeuronInformationmatPanel);
            app.DonotdocorrectionCheckBox.Text = 'Do not do correction!';
            app.FOVEditFieldLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.FOVEditFieldLabel.Text = 'FOV';
            app.FOVEditField = uieditfield(app.MINI2PImageNeuronInformationmatPanel, 'numeric');
            app.FOVEditField.Editable = 'off';
            app.ReplaceButton = uibutton(app.MINI2PImageNeuronInformationmatPanel, 'push');
            app.ReplaceButton.Text = 'Replace';
            app.ProcessedDataInputPanel = uipanel(app.UIFigure);
            app.ProcessedDataInputPanel.Title = 'Processed Data Input ';
            app.SelectButton_2 = uibutton(app.ProcessedDataInputPanel, 'push');
            app.SelectButton_2.Text = 'Select';
            app.OutputButton = uibutton(app.ProcessedDataInputPanel, 'push');
            app.OutputButton.Text = 'Output ';
            app.FilepathEditField_2Label = uilabel(app.ProcessedDataInputPanel);
            app.FilepathEditField_2Label.Text = 'File path';
            app.FilepathEditField_2 = uieditfield(app.ProcessedDataInputPanel, 'text');
            app.InputButton = uibutton(app.ProcessedDataInputPanel, 'push');
            app.InputButton.Text = 'Input';
            app.StitchingPreviewPanel = uipanel(app.UIFigure);
            app.StitchingPreviewPanel.Title = 'Stitching Preview';
            app.WFAxes = uiaxes(app.StitchingPreviewPanel);
            title(app.WFAxes, 'Stitching pannel')
            xlabel(app.WFAxes, 'X')
            ylabel(app.WFAxes, 'Y')
            app.RetinotopicMappingPanel = uipanel(app.UIFigure);
            app.RetinotopicMappingPanel.Title = 'Retinotopic Mapping';
            app.RetMapEditFieldLabel = uilabel(app.RetinotopicMappingPanel);
            app.RetMapEditFieldLabel.Text = 'Ret.Map';
            app.RetMapEditField = uieditfield(app.RetinotopicMappingPanel, 'text');
            app.SelectRMButton = uibutton(app.RetinotopicMappingPanel, 'push');
            app.SelectRMButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRMButtonPushed, true);
            app.SelectRMButton.Text = 'Select';
            app.AlphaSliderLabel_2 = uilabel(app.RetinotopicMappingPanel);
            app.AlphaSliderLabel_2.Text = 'Alpha';
            app.RM_alpha = uislider(app.RetinotopicMappingPanel);
            app.RM_alpha.Limits = [0 1];
            app.RM_alpha.ValueChangedFcn = createCallbackFcn(app, @RM_alphaValueChanged, true);
            app.RM_alpha.Value = 0.8;
            app.MeandFFEditFieldLabel = uilabel(app.RetinotopicMappingPanel);
            app.MeandFFEditFieldLabel.Text = 'Mean dF/F';
            app.MeandFFEditField = uieditfield(app.RetinotopicMappingPanel, 'text');
            app.SelectDFButton = uibutton(app.RetinotopicMappingPanel, 'push');
            app.SelectDFButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDFButtonPushed, true);
            app.SelectDFButton.Text = 'Select';
            app.DeleteRM = uibutton(app.RetinotopicMappingPanel, 'push');
            app.DeleteRM.ButtonPushedFcn = createCallbackFcn(app, @DeleteRMButtonPushed, true);
            app.DeleteRM.Text = 'Delete';
            app.MINI2PCorrectionTransfomrMatrixPanel = uipanel(app.UIFigure);
            app.MINI2PCorrectionTransfomrMatrixPanel.Title = 'MINI2P Correction Transfomr Matrix';
            app.LoadButton = uibutton(app.MINI2PCorrectionTransfomrMatrixPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.FilepathEditField_4Label = uilabel(app.MINI2PCorrectionTransfomrMatrixPanel);
            app.FilepathEditField_4Label.Text = 'File path';
            app.TransMatrix_filepath = uieditfield(app.MINI2PCorrectionTransfomrMatrixPanel, 'text');
            app.TransMatrix_filepath.Value = '''\\forskning.it.ntnu.no\ntnu\mh-kin\moser\open2pmini\FOV callibration\OPENMINI2P2_Workshop\LFOV001\AVG_OpenMINI2P_LFOV001_50umGrind_256_00001_2021-03-10-16-16-17.mat';
            app.CroesscorrelationPanel = uipanel(app.UIFigure);
            app.CroesscorrelationPanel.Title = 'Croess-correlation';
            app.CheckCorrelationButton = uibutton(app.CroesscorrelationPanel, 'push');
            app.CheckCorrelationButton.Text = 'Check Correlation';
            app.TotheWidefieldCheckBox = uicheckbox(app.CroesscorrelationPanel);
            app.TotheWidefieldCheckBox.Text = 'To the Wide-field';
            app.RangeLabel = uilabel(app.CroesscorrelationPanel);
            app.RangeLabel.Text = '+/-Range';
            app.RangeEditField = uieditfield(app.CroesscorrelationPanel, 'numeric');
            app.RangeEditField.Value = 5;
            app.UIAxes2 = uiaxes(app.CroesscorrelationPanel);
            title(app.UIAxes2, 'Cross-correlation')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.PeakvalueEditFieldLabel = uilabel(app.CroesscorrelationPanel);
            app.PeakvalueEditFieldLabel.Text = 'Peak value';
            app.PeakvalueEditField = uieditfield(app.CroesscorrelationPanel, 'numeric');
            app.XEditFieldLabel = uilabel(app.CroesscorrelationPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.CroesscorrelationPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.CroesscorrelationPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.CroesscorrelationPanel, 'numeric');
            app.MinoverlapEditFieldLabel = uilabel(app.CroesscorrelationPanel);
            app.MinoverlapEditFieldLabel.Text = 'Min overlap';
            app.MinoverlapEditField = uieditfield(app.CroesscorrelationPanel, 'numeric');
            app.MinoverlapEditField.Value = 100;
            app.RemoverepeatedcellsPanel = uipanel(app.UIFigure);
            app.RemoverepeatedcellsPanel.Title = 'Remove repeated cells';
            app.OverlapthresholdEditFieldLabel = uilabel(app.RemoverepeatedcellsPanel);
            app.OverlapthresholdEditFieldLabel.Text = 'Overlap threshold';
            app.OverlapthresholdEditField = uieditfield(app.RemoverepeatedcellsPanel, 'numeric');
            app.OverlapthresholdEditField.Value = 75;
            app.Label = uilabel(app.RemoverepeatedcellsPanel);
            app.Label.Text = '%';
            app.RemoverepearedcellsButton = uibutton(app.RemoverepeatedcellsPanel, 'push');
            app.RemoverepearedcellsButton.Text = 'Remove  repeared cells';
            app.KeepcellDropDownLabel = uilabel(app.RemoverepeatedcellsPanel);
            app.KeepcellDropDownLabel.Text = 'Keep cell ';
            app.KeepcellDropDown = uidropdown(app.RemoverepeatedcellsPanel);
            app.KeepcellDropDown.Items = {'All', 'HighestSNR', 'HighestMeandf', 'None'};
            app.KeepcellDropDown.Value = 'HighestSNR';
            app.WidefieldtransformationPanel = uipanel(app.UIFigure);
            app.WidefieldtransformationPanel.Title = 'Wide-field transformation';
            app.ShiftXSpinnerLabel = uilabel(app.WidefieldtransformationPanel);
            app.ShiftXSpinnerLabel.Text = 'Shift-X';
            app.WF_ShiftX = uispinner(app.WidefieldtransformationPanel);
            app.WF_ShiftX.ValueChangedFcn = createCallbackFcn(app, @WF_ShiftXValueChanged, true);
            app.ShiftYSpinnerLabel = uilabel(app.WidefieldtransformationPanel);
            app.ShiftYSpinnerLabel.Text = 'Shift-Y';
            app.WF_ShiftY = uispinner(app.WidefieldtransformationPanel);
            app.WF_ShiftY.ValueChangedFcn = createCallbackFcn(app, @WF_ShiftYValueChanged, true);
            app.SizeSpinnerLabel = uilabel(app.WidefieldtransformationPanel);
            app.SizeSpinnerLabel.Text = 'Size';
            app.WF_Resize = uispinner(app.WidefieldtransformationPanel);
            app.WF_Resize.Step = 0.05;
            app.WF_Resize.ValueChangedFcn = createCallbackFcn(app, @WF_ResizeValueChanged, true);
            app.WF_Resize.Value = 1;
            app.RotateSpinnerLabel = uilabel(app.WidefieldtransformationPanel);
            app.RotateSpinnerLabel.Text = 'Rotate';
            app.WF_Rotate = uispinner(app.WidefieldtransformationPanel);
            app.WF_Rotate.Step = 0.1;
            app.WF_Rotate.Limits = [-360 360];
            app.WF_Rotate.ValueChangedFcn = createCallbackFcn(app, @WF_RotateValueChanged, true);
            app.WF_SelectFile_2 = uibutton(app.WidefieldtransformationPanel, 'push');
            app.WF_SelectFile_2.ButtonPushedFcn = createCallbackFcn(app, @WF_SelectFile_2ButtonPushed, true);
            app.WF_SelectFile_2.Text = 'Reset';
            app.TextboxListBoxLabel = uilabel(app.UIFigure);
            app.TextboxListBoxLabel.Text = 'Textbox';
            app.Textbox = uilistbox(app.UIFigure);
            app.Textbox.Items = {};
            app.Textbox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StitchingChecker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end